classdef Automatic_Outlining_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout4                matlab.ui.container.GridLayout
        DeleteUnselectedButton     matlab.ui.control.Button
        ImageNumEditField          matlab.ui.control.NumericEditField
        ImageNumEditFieldLabel     matlab.ui.control.Label
        CellBrightEditField        matlab.ui.control.NumericEditField
        CellBrightEditFieldLabel   matlab.ui.control.Label
        NucBrightEditField         matlab.ui.control.NumericEditField
        NucBrightEditFieldLabel    matlab.ui.control.Label
        BorderEditField            matlab.ui.control.NumericEditField
        BorderEditFieldLabel       matlab.ui.control.Label
        EdgeMinimumEditField       matlab.ui.control.NumericEditField
        EdgeMinimumEditFieldLabel  matlab.ui.control.Label
        DrawNucButton              matlab.ui.control.Button
        LoadFolderButton           matlab.ui.control.Button
        SegmentButton              matlab.ui.control.Button
        DrawCellButton             matlab.ui.control.Button
        DeleteSelectedButton       matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        PreviousButton             matlab.ui.control.Button
        NextButton                 matlab.ui.control.Button
        NumLevelsEditField         matlab.ui.control.NumericEditField
        NumLevelsEditFieldLabel    matlab.ui.control.Label
        NumCellsEditField          matlab.ui.control.NumericEditField
        NumCellsEditFieldLabel     matlab.ui.control.Label
        CytoChannelEditField       matlab.ui.control.EditField
        CytoChannelEditFieldLabel  matlab.ui.control.Label
        NucChannelEditField        matlab.ui.control.EditField
        NucChannelEditFieldLabel   matlab.ui.control.Label
        ImageAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ImagePairs
        CurrentIndex = 1
        CurrentCellMasks     % Cell array storing binary masks of each cell
        CurrentNucMasks      % Cell array storing binary masks of each nucleus
        CurrentFolder
        CellROIs
        NucROIs
        SelectedCellROIs logical  % tracks which cell ROIs are selected
        SelectedNucROIs logical   % tracks which nucleus ROIs are selected
        imageSize
        CachedNucImg
        CachedCytoImg
        NucleusCentroids  
        FilteredNucCentroids
        IsDrawingCells logical = false;
        IsDrawingNucs  logical = false;
        BaseImage  matlab.graphics.primitive.Image
    end

    methods (Access = private)

        function idx = getCellIndex(app, roi)
            idx = find(app.CellROIs == roi, 1);
        end
        
        function idx = getNucIndex(app, roi)
            idx = find(app.NucROIs == roi, 1);
        end
        
        function img = getProjectedImage(app, filename, isNuc)
            % if requesting nucleus and cached exists, return it
            if isNuc
                if ~isempty(app.CachedNucImg)
                    img = app.CachedNucImg;
                    return;
                end
            else
                if ~isempty(app.CachedCytoImg)
                    img = app.CachedCytoImg;
                    return;
                end
            end
        
            % otherwise read and max project
            info = imfinfo(filename);
            nSlices = numel(info);
            img = im2double(imread(filename, 1));
        
            if nSlices > 1
                [h, w] = size(img);
                stack = zeros(h, w, nSlices, 'like', img);
                stack(:,:,1) = img;
                for k = 2:nSlices
                    stack(:,:,k) = im2double(imread(filename, k));
                end
                img = max(stack, [], 3);
            end
            
            % store in cache
            if isNuc
                app.CachedNucImg = img;
            else
                app.CachedCytoImg = img;
            end
        end

        function clearCurrentState(app)
            % delete cell ROIs from figure
            if ~isempty(app.CellROIs)
                for i = 1:numel(app.CellROIs)
                    if isvalid(app.CellROIs(i))
                        delete(app.CellROIs(i));
                    end
                end
            end
        
            % delete nuc ROIs from figure
            if ~isempty(app.NucROIs)
                for i = 1:numel(app.NucROIs)
                    if isvalid(app.NucROIs(i))
                        delete(app.NucROIs(i));
                    end
                end
            end
        
            % clear arrays / masks / selections
            app.CellROIs          = [];
            app.NucROIs           = [];
            app.CurrentCellMasks  = [];
            app.CurrentNucMasks   = [];
            app.SelectedCellROIs  = [];
            app.SelectedNucROIs   = [];
            app.FilteredNucCentroids = [];

            % GW - Clear remaining graphical elements (hard wipe bc nuclei 
            % were staying if user hit 'Segment' before moving to next img)
            delete(app.ImageAxes.Children);
        

            % clear cached images
            app.CachedNucImg  = [];
            app.CachedCytoImg = [];
            app.NumCellsEditField.Value = -1;
            app.NumLevelsEditField.Value = 20;
        end
        
        function DisplayNucOverlay(app, index)
            % --- Read nucleus image
            nucImg  = getProjectedImage(app, app.ImagePairs(index).nucPath, true);
            nucEnhanced = nucImg * app.NucBrightEditField.Value;
        
            % --- Convert to blue-only RGB
            scale_factor = 1/5;
            nucScale = imresize(nucEnhanced, scale_factor);
            [rows, cols] = size(nucScale);
            rgbImg = zeros(rows, cols, 3);
            rgbImg(:,:,3) = nucScale;   % blue channel
        
            % --- Ensure base image exists ---
            if isempty(app.BaseImage) || ~isvalid(app.BaseImage)
                % If for some reason it's not there yet, just create it once
                hIm = imshow(rgbImg, 'Parent', app.ImageAxes);
                app.ImageAxes.CLim = [min(NucEnhanced(:)) max(NucEnhanced(:))];

                app.BaseImage = hIm;
                hold(app.ImageAxes, 'on');

                % GW: lock axes to prevent inadvertent zooming in w/ button presses
                axis(app.ImageAxes, 'image');
                axis(app.ImageAxes, 'off');
                axis(app.ImageAxes, 'manual');

            else
                % GW: Update the image pixels without changing axes limits
                xlimOld = app.ImageAxes.XLim;
                ylimOld = app.ImageAxes.YLim;

                % Just change the data behind existing image
                app.BaseImage.CData = rgbImg;

                % Restore zoom/pan
                app.ImageAxes.XLim = xlimOld;
                app.ImageAxes.YLim = ylimOld;
            end

            title(app.ImageAxes, sprintf('Nuc only: %s', app.ImagePairs(index).basename));
            %axis(app.ImageAxes, 'image');
            %axis(app.ImageAxes, 'off');
            %app.ImageAxes.InnerPosition = app.ImageAxes.OuterPosition;
            %app.ImageAxes.XLabel.String = '';
            %app.ImageAxes.YLabel.String = '';
        end    

        function DisplayOverlay(app, index)
            % --- Read images
            nucImg  = getProjectedImage(app, app.ImagePairs(index).nucPath, true);
            cytoImg = getProjectedImage(app, app.ImagePairs(index).cytoPath, false);
        
            % --- Resize if needed
            if ~isequal(size(nucImg), size(cytoImg))
                cytoImg = imresize(cytoImg, size(nucImg));
            end
        
            % --- Enhance channels
            se = strel('disk', 3);
            cytoDilated  = imdilate(cytoImg, se);

            %cytoDilated = cytoDilated - mean(cytoDilated);
            %cytoEnhanced = cytoDilated - min(cytoDilated(:));
            %if max(cytoEnhanced(:)) > 0
            %    cytoEnhanced = cytoEnhanced / max(cytoEnhanced(:));
            %end

            cytoEnhanced = cytoDilated *app.CellBrightEditField.Value;
            cytoEnhanced(cytoEnhanced > 1) = 1;
            nucEnhanced  = nucImg * app.NucBrightEditField.Value;
        
            % --- Convert to RGB
            scale_factor = 1/5;
            nucScale  = imresize(nucEnhanced, scale_factor);
            cytoScale = imresize(cytoEnhanced, scale_factor);
            [rows, cols] = size(nucScale);
            rgbImg = zeros(rows, cols, 3);
            rgbImg(:,:,2) = cytoScale;   % green
            rgbImg(:,:,3) = nucScale;    % blue
        
            % --- Create or update image object ---
            if isempty(app.BaseImage) || ~isvalid(app.BaseImage)
                % First time: create image and hold on so future objects stay
                hIm = imshow(rgbImg, 'Parent', app.ImageAxes);

                app.ImageAxes.CLim = [min(cytoEnhanced(:)) max(cytoEnhanced(:))];

                app.BaseImage = hIm;
                hold(app.ImageAxes, 'on');

                % GW: lock axes to prevent inadvertent zooming in w/ button presses
                axis(app.ImageAxes, 'image');
                axis(app.ImageAxes, 'off');
                axis(app.ImageAxes, 'manual');
            else
                % GW: Update the image pixels without changing axes limits
                xlimOld = app.ImageAxes.XLim;
                ylimOld = app.ImageAxes.YLim;

                % Just update the pixels, ROIs stay untouched
                app.BaseImage.CData = rgbImg;

                % Restore previous zoom/pan
                app.ImageAxes.XLim = xlimOld;
                app.ImageAxes.YLim = ylimOld;
            end

            title(app.ImageAxes, sprintf('Overlay: %s', app.ImagePairs(index).basename), 'Interpreter', 'none');
            %axis(app.ImageAxes, 'image');
            %axis(app.ImageAxes, 'off');
            %app.ImageAxes.InnerPosition = app.ImageAxes.OuterPosition;
            %app.ImageAxes.XLabel.String = '';
            %app.ImageAxes.YLabel.String = '';
        end

        function updateMask(app, roi)
            idx = find(app.CellROIs == roi, 1);
            if isempty(idx) || idx < 1
                return;
            end

            pos = roi.Position;
            app.CurrentCellMasks{idx} = pos;
            toggleCellSelection(app, roi);
        end

        function updateNucMask(app, roi)
            idx = getNucIndex(app, roi);
            if isempty(idx) || idx < 1
                return;
            end
        
            pos = roi.Position;
            app.CurrentNucMasks{idx} = pos;
            toggleNucSelection(app, roi);
        end
        
        function bringNucleiToFront(app)
            % Ensure nuclei ROIs always appear above cell ROIs
            if ~isempty(app.NucROIs)
                for i = 1:numel(app.NucROIs)
                    if isvalid(app.NucROIs(i))
                        uistack(app.NucROIs(i), 'top');
                    end
                end
            end
        end

        function toggleCellSelection(app, roi)
            idx = getCellIndex(app, roi);
            if isempty(idx) || idx < 1
                return; % ROI was deleted or not found
            end
        
            app.SelectedCellROIs(idx) = ~app.SelectedCellROIs(idx);
        
            if app.SelectedCellROIs(idx)
                roi.Color = [1 0 0];    % red = selected
                roi.Selected = true;
            else
                roi.Color = [0 0.6 0];  % green = deselected
                roi.Selected = false;
            end
        end

        function toggleNucSelection(app, roi)
            idx = getNucIndex(app, roi);
            if isempty(idx) || idx < 1
                return;  % ROI no longer tracked
            end
        
            % flip selection state
            app.SelectedNucROIs(idx) = ~app.SelectedNucROIs(idx);
        
            if app.SelectedNucROIs(idx)
                roi.Color = [1 0 0];   % red when selected
                roi.Selected = true;
            else
                roi.Color = [0 0 0.6]; % blue-ish when not selected
                roi.Selected = false;
            end
        end

        function updateDrawingButtons(app)
            % Default App Designer gray
            defaultColor = [0.94 0.94 0.94];  
            activeColor  = [0.8 1.0 0.8];     % light green
        
            % Cell drawing button
            if app.IsDrawingCells
                app.DrawCellButton.BackgroundColor = activeColor;
                app.DrawCellButton.FontWeight      = 'bold';
            else
                app.DrawCellButton.BackgroundColor = defaultColor;
                app.DrawCellButton.FontWeight      = 'normal';
            end
        
            % Nuc drawing button
            if app.IsDrawingNucs
                app.DrawNucButton.BackgroundColor = activeColor;
                app.DrawNucButton.FontWeight      = 'bold';
            else
                app.DrawNucButton.BackgroundColor = defaultColor;
                app.DrawNucButton.FontWeight      = 'normal';
            end
        end
        
        function segmentation(app, index)
            nLev   = app.NumLevelsEditField.Value;
            imgname = app.ImagePairs(index).cytoPath;
            imgDAPI = app.ImagePairs(index).nucPath;
            
            % get (cached) projected DAPI
            dapiorg = getProjectedImage(app, imgDAPI, true);
            dapi    = imresize(dapiorg, 1/5);
            dapi    = double(dapi);
            
            % normalize 0..1
            dapi = dapi - min(dapi(:));
            if max(dapi(:)) > 0
                dapi = dapi ./ max(dapi(:));
            end
            
            % threshold (Otsu)
            T = graythresh(dapi);
            nucMask = dapi > T;
            
            % basic clean-up
            nucMask = bwareaopen(nucMask, 50);            % remove tiny specks
            nucMask = imfill(nucMask, 'holes');           % fill holes
            
            D    = bwdist(~nucMask);        
            Dneg = -D;
            Dneg(~nucMask) = -Inf;           
            
            fgm  = imextendedmin(D, 4);      
            Dmod = imimposemin(Dneg, fgm);
            L    = watershed(Dmod);
            
            nucMask(L == 0) = 0;              
            
            nucMask = bwconvhull(nucMask, 'objects');
            
            % now get connected nuclei
            CC    = bwconncomp(nucMask);
            nucN  = CC.NumObjects;
            
            stats = regionprops(CC, 'ConvexHull');

            % GW: before clearing old nuc ROIs, delete actual old nucleus ROIs from figure
            if ~isempty(app.NucROIs)
                for i = 1:numel(app.NucROIs)
                    if isvalid(app.NucROIs(i))
                        delete(app.NucROIs(i));
                    end
                end
            end
            % clear old nuc ROIs
            app.CurrentNucMasks = {};  % masks that are kept
            app.NucROIs = gobjects(0);
            app.NucleusCentroids = [];  % reset centroids
            app.FilteredNucCentroids = [];
            
            hold(app.ImageAxes, 'on');
            margin = app.BorderEditField.Value;
            imgHeight = app.imageSize(1);
            imgWidth = app.imageSize(2);

            scaleFactor = 1/5
            imgHeight = round(imgHeight * scaleFactor);
            imgWidth = round(imgWidth * scaleFactor);
            
            % Precompute centroids for all nuclei (convex hull centroids)
            allCentroids = zeros(nucN,2);
            for i = 1:nucN
                pg = polyshape(stats(i).ConvexHull);
                [cx, cy] = centroid(pg);
                allCentroids(i,:) = [cx, cy];
            end

            % Compute distance matrix
            distMatrix = squareform(pdist(allCentroids));
            minDist = 40;
            % Identify all nuclei too close to another nucleus
            tooClose = false(nucN,1);
            for i = 1:nucN
                % ignore self (distance=0)
                if any(distMatrix(i,[1:i-1,i+1:end]) < minDist)
                    tooClose(i) = true;
                end
            end

            for i = 1:nucN
                hullForced = stats(i).ConvexHull;   % Nx2, columns = [x y]
                hull = reducepoly(hullForced, 0.01);
                pixels = CC.PixelIdxList{i};
                [y, x] = ind2sub([imgHeight, imgWidth], pixels);
                k = boundary(x, y, 1);   % shrink factor 1 = tight boundary
                hullTrueXY = [x(k), y(k)];
                areaTrue = polyarea(hullTrueXY(:,1), hullTrueXY(:,2));
                areaForced = polyarea(hullForced(:,1), hullForced(:,2));
                convexityRatio = (areaForced - areaTrue) / areaTrue;
                maxConvexityRatio = 0.1;
                
                % Filtering conditions
                % Filter for nearness to edge
                if any(hull(:,1) < margin) || any(hull(:,1) > imgWidth - margin) || ...
                    any(hull(:,2) < margin) || any(hull(:,2) > imgHeight - margin)
                    % add to filtered masks
                    app.FilteredNucCentroids(end+1,:) = allCentroids(i,:);
                    continue;  % skip this nucleus
                % Filter for closeness to other nuclei
                elseif tooClose(i)
                    app.FilteredNucCentroids(end+1,:) = allCentroids(i,:);
                    continue
                elseif convexityRatio > maxConvexityRatio
                    app.FilteredNucCentroids(end+1,:) = allCentroids(i,:);
                    continue;  % skip this nucleus
                end
                
                % make editable ROI
                hNuc = drawpolygon(app.ImageAxes, ...
                    'Position', hull, ...
                    'Color', [0 0 0.6], ...
                    'LineWidth', 1.5, ...
                    'FaceAlpha', 0.1, ...
                    'InteractionsAllowed', 'all');
                
                app.NucROIs(end+1) = hNuc;
                app.CurrentNucMasks{end+1} = hull;
                app.NucleusCentroids(end+1,:) = allCentroids(i,:);

                app.NucROIs(end).SelectedColor = [1 0 0];
                addlistener(hNuc, 'ROIClicked', @(src, evt) toggleNucSelection(app, src));
                addlistener(hNuc, 'ROIMoved',   @(src, evt) updateNucMask(app, src));
            end

            nCells = numel(app.CurrentNucMasks);
            app.NumCellsEditField.Value = nCells;
            hold(app.ImageAxes, 'off');
            
            %% Load image and plot 
            
            Iorg = getProjectedImage(app, imgname, false);
            I = imresize(Iorg,1/5); % compress for faster computations
            I = double(I); % for TIF, convert to operable double matrix 
            
            levthresh = max(max(I))*0.8; % take midpoint intensity 
            
            %I = flipud(I); % flip image in y for further analysis, to compensate for the fact that it flips it in the first place
            
            % CALCULATE CONTOURS
            M = contourc(I,nLev); 
            
            %% Extract single contour and add to filtered coordinates if it's below the prescribed threshold 
            ci = 1; % Start index for the current contour segment
            x_coords = [];
            y_coords = [];
            while ci < size(M,2)
                level = M(1,ci);
                num_points = M(2,ci);
                
                if level < levthresh % Impose level curve threshold 
                    x_coords = [x_coords, M(1, ci+1 : ci+num_points)];
                    y_coords = [y_coords, M(2, ci+1 : ci+num_points)];
                end
                ci = ci + num_points + 1;
            end


            %% Use dbscan to remove small blips outside cells — may need to adjust parameters 
            epsilon = 20;   %distance threshold 
            minPts  = 20;   %minimum points to be considered a cluster
            points = [x_coords;y_coords]';
            dbi = dbscan(points, epsilon, minPts);
            % Identify cluster sizes
            clusterIDs = unique(dbi(dbi > 0));
            clusterSizes = arrayfun(@(c) sum(dbi == c), clusterIDs);
            % Keep only large clusters
            minClusterSize = minPts; % number of points
            bigClusters = clusterIDs(clusterSizes > minClusterSize);
            % Filter data
            keep = ismember(dbi, bigClusters);
            x_clean = x_coords(keep);
            y_clean = y_coords(keep);
            points_clean = [x_clean; y_clean]';
            

            % Combine kept + filtered nuclei
            allCentroids = [app.NucleusCentroids; app.FilteredNucCentroids];  
            numKeptNuclei = size(app.NucleusCentroids,1);
            
            % Compute distances from points to all nuclei
            D = pdist2(points_clean, allCentroids, 'euclidean');  
            [minDist, idxAll] = min(D, [], 2);  % closest nucleus and its distance
            
            % Keep only points assigned to non-filtered nuclei and within maxDist
            maxDist = 80;
            keep = (idxAll <= numKeptNuclei) & (minDist < maxDist);
            
            % Apply filtering
            points_clean = points_clean(keep, :);
            idx = idxAll(keep); 
            
            if ~isempty(app.CellROIs)
                delete(app.CellROIs(ishandle(app.CellROIs)));  % remove old ones
            end
            app.CellROIs = gobjects(0);  % reset handle array
            hold(app.ImageAxes, 'on');

            for i = 1:nCells
                xc = points_clean(idx==i,1); 
                yc = points_clean(idx==i,2);
                k = boundary(xc,yc,0.2);
                P = [xc(k), yc(k)];
                Pred = reducepoly(P,app.EdgeMinimumEditField.Value);

                h = drawpolygon(app.ImageAxes, ...
                    'Position', Pred, ...
                    'Color', [0 0.6 0], ...
                    'LineWidth', 1.5, ...
                    'FaceAlpha', 0.1, ...
                    'InteractionsAllowed', 'all');  % allow dragging of vertices and shape
            
                % Store ROI handle
                app.CellROIs(i) = h;
                app.CellROIs(i).SelectedColor = [1 0 0];  % red highlight
                addlistener(h, 'ROIClicked', @(src, evt) toggleCellSelection(app, src));
                addlistener(h, 'ROIMoved',   @(src, evt) updateMask(app, src));
            
                % Initial mask
                app.CurrentCellMasks{i} = Pred;
            end
            bringNucleiToFront(app)
            % Starts the cells as not selected
            app.SelectedCellROIs = false(1, nCells);
            app.SelectedNucROIs = false(1, nCells);
        end
        
        function reorder(app)
            cellMasks = app.CurrentCellMasks;   % cell array of cell positions (scaled coords)
            nucMasks  = app.CurrentNucMasks;    % cell array of nucleus positions (scaled coords)
        
            numCells = numel(cellMasks);
            numNucs  = numel(nucMasks);
        
            newNucMasks = cell(1, numCells);    % reordered nuclei masks
            assignedNucs = false(1, numNucs);   % track assigned nuclei
        
            for c = 1:numCells
                bestNuc = 0;
                maxOverlap = -Inf;
        
                % Convert current cell polygon to mask
                cellPos = cellMasks{c};
                rows = ceil(max(cellPos(:,2))) + 1;
                cols = ceil(max(cellPos(:,1))) + 1;
                cellMask = poly2mask(cellPos(:,1), cellPos(:,2), rows, cols);
        
                for n = 1:numNucs
                    if assignedNucs(n)
                        continue
                    end
        
                    % Convert nucleus polygon to mask (same size)
                    nucPos = nucMasks{n};
                    nucMask = poly2mask(nucPos(:,1), nucPos(:,2), rows, cols);
        
                    % Compute overlap pixels
                    overlap = sum(cellMask(:) & nucMask(:));
        
                    if overlap > maxOverlap
                        maxOverlap = overlap;
                        bestNuc = n;
                    end
                end
        
                if bestNuc > 0
                    newNucMasks{c} = nucMasks{bestNuc};
                    assignedNucs(bestNuc) = true;
                else
                    newNucMasks{c} = [];  % no matching nucleus
                end
            end
        
            % Update app property
            app.CurrentNucMasks = newNucMasks;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed2(app, event)
            if isempty(app.CurrentCellMasks)
                uialert(app.UIFigure, 'No masks to save.', 'Error');
                return;
            end

            numCells = numel(app.CurrentCellMasks);
            numNucs  = numel(app.CurrentNucMasks);
            if numCells ~= numNucs
                msg = sprintf(['Number of cell masks (%d) does not match number of nucleus masks (%d).\n' ...
                               'Please ensure segmentation is correct before saving.'], numCells, numNucs);
                uialert(app.UIFigure, msg, 'Mismatch Detected', 'Icon', 'warning');
                return;
            end
        
            % Ask user for folder to save masks
            saveFolder = fullfile(app.CurrentFolder, "SkeletonAndPrint");
            if ~exist(saveFolder, 'dir')
                mkdir(saveFolder);  % Create the folder if it doesn't exist
            end
            
            scaleFactor = 1/5;
            rows = app.imageSize(1);
            cols = app.imageSize(2);
            
            rowsSmall = round(rows * scaleFactor);
            colsSmall = round(cols * scaleFactor);
            reorder(app);
            % Loop through masks and save each one
            for i = 1:length(app.CurrentCellMasks)
                mask_position = app.CurrentCellMasks{i};
                maskSmall = poly2mask(mask_position(:,1), mask_position(:,2), rowsSmall, colsSmall); 
                maskLarge = imresize(maskSmall, [rows cols], 'nearest');

                nucMask_position = app.CurrentNucMasks{i};
                nucMaskSmall = poly2mask(nucMask_position(:,1), nucMask_position(:,2), rowsSmall, colsSmall);
                nucMaskLarge = imresize(nucMaskSmall, [rows cols], 'nearest');
                
                % Ensure it's logical / binary
                maskLarge = logical(maskLarge);
                nucMaskLarge = logical(nucMaskLarge);
        
                % Construct filename
                baseName = app.ImagePairs(app.CurrentIndex).basename;
                cellChan = strtrim(app.CytoChannelEditField.Value);
                nucChan  = strtrim(app.NucChannelEditField.Value);
                filename = fullfile(saveFolder, sprintf('%s%s_print_%d.gif', baseName, cellChan, i));
                filenameNuc = fullfile(saveFolder, sprintf('%s%s_nuc_%d.gif', baseName, nucChan, i));
        
                % Save mask as binary GIF
                imwrite(maskLarge, filename, 'gif');
                imwrite(nucMaskLarge, filenameNuc, 'gif');
            end
        
            uialert(app.UIFigure, 'Masks saved successfully!', 'Success');
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed3(app, event)
            % Check if images are loaded
            if isempty(app.ImagePairs)
                uialert(app.UIFigure, 'No images loaded.', 'Error');
                return;
            end
        
            % Check if already at the first image
            if app.CurrentIndex <= 1
                uialert(app.UIFigure, 'This is the first image.', 'Start of Images');
                return;
            end

            clearCurrentState(app);

            % --- Decrement index
            app.CurrentIndex = app.CurrentIndex - 1;
            app.ImageNumEditField.Value = app.CurrentIndex;
        
            % Display previous image overlay
            DisplayOverlay(app, app.CurrentIndex);
            app.NumCellsEditField.Value = -1;
            app.NumLevelsEditField.Value = 20;
            segmentation(app, app.CurrentIndex)
        end

        % Button pushed function: NextButton
        function NextButtonPushed3(app, event)
            % Check if there are images loaded
            if isempty(app.ImagePairs)
                uialert(app.UIFigure, 'No images loaded.', 'Error');
                return;
            end
        
            % Check if already at the last image
            if app.CurrentIndex >= numel(app.ImagePairs)
                uialert(app.UIFigure, 'This is the last image.', 'End of Images');
                return;
            end

            clearCurrentState(app);
        
            % Increment index
            app.CurrentIndex = app.CurrentIndex + 1;
            app.ImageNumEditField.Value = app.CurrentIndex;
        
            % Display the new overlay
            DisplayOverlay(app, app.CurrentIndex);
            app.NumCellsEditField.Value = -1;
            app.NumLevelsEditField.Value = 20;
            segmentation(app, app.CurrentIndex)
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed2(app, event)
            DisplayOverlay(app, app.CurrentIndex)
            segmentation(app, app.CurrentIndex)
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed3(app, event)
            clearCurrentState(app);

            % --- Get channel identifiers
            nucID = strtrim(app.NucChannelEditField.Value);
            cytoID = strtrim(app.CytoChannelEditField.Value);
        
            if isempty(nucID) || isempty(cytoID)
                uialert(app.UIFigure, 'Please enter both nucleus and cytoplasm identifiers.', 'Missing Input');
                return;
            end
        
            % --- Select folder
            folder = uigetdir('', 'Select folder containing images');
            app.CurrentFolder = folder;
            if folder == 0, return; end
        
            % --- Gather all image files
            exts = {'*.tif','*.tiff'};
            allFiles = [];
            for i = 1:length(exts)
                allFiles = [allFiles; dir(fullfile(folder, exts{i}))];
            end
            
            % --- Separate based on user-entered identifiers
            nucFiles = allFiles(contains({allFiles.name}, nucID, 'IgnoreCase', true));
            cytoFiles = allFiles(contains({allFiles.name}, cytoID, 'IgnoreCase', true));
        
            if isempty(nucFiles) || isempty(cytoFiles)
                uialert(app.UIFigure, sprintf('Could not find files containing "%s" and "%s".', nucID, cytoID), 'Missing Files');
                return;
            end
        
            % --- Clean up names by removing channel identifiers and extensions
            cleanNucNames  = erase({nucFiles.name},  nucID);
            cleanCytoNames = erase({cytoFiles.name}, cytoID);
            cleanNucNames  = erase(cleanNucNames, {'.tif', '.tiff'});
            cleanCytoNames = erase(cleanCytoNames, {'.tif', '.tiff'});
        
            % --- Match files
            [sharedNames, nucIdx, cytoIdx] = intersect(cleanNucNames, cleanCytoNames);
        
            if isempty(sharedNames)
                uialert(app.UIFigure, 'No matched file pairs found.', 'Error');
                return;
            end
        
            % --- Store matched pairs
            app.ImagePairs = struct([]);
            for i = 1:length(sharedNames)
                app.ImagePairs(i).nucPath = fullfile(nucFiles(nucIdx(i)).folder, nucFiles(nucIdx(i)).name);
                app.ImagePairs(i).cytoPath = fullfile(cytoFiles(cytoIdx(i)).folder, cytoFiles(cytoIdx(i)).name);
                app.ImagePairs(i).basename = sharedNames{i};
            end

            [~, order] = sort({app.ImagePairs.basename});
            app.ImagePairs = app.ImagePairs(order);
            
            requestedIndex = round(app.ImageNumEditField.Value);
            requestedIndex = max(1, min(requestedIndex, numel(app.ImagePairs)));

            app.CurrentIndex = requestedIndex;
            
            firstNucFile = fullfile(nucFiles(app.CurrentIndex).folder, nucFiles(app.CurrentIndex).name);
            firstNucImg  = getProjectedImage(app, firstNucFile, true);
            [rows, cols, ~] = size(firstNucImg);
            app.imageSize = [rows, cols];

            DisplayOverlay(app, app.CurrentIndex);
            segmentation(app, app.CurrentIndex);
        end

        % Button pushed function: DeleteSelectedButton
        function DeleteSelectedButtonPushed(app, event)
            % --- Delete selected Cell ROIs ---
            if ~isempty(app.CellROIs)
                % Find indices of selected cells
                delIdx = find(app.SelectedCellROIs);
                for i = 1:numel(delIdx)
                    idx = delIdx(i);
                    if isvalid(app.CellROIs(idx))
                        delete(app.CellROIs(idx)); % delete the ROI from the UI
                    end
                end
                % Remove all at once — avoids shifting issues
                app.CellROIs(delIdx) = [];
                app.CurrentCellMasks(delIdx) = [];
                app.SelectedCellROIs(delIdx) = [];
            end
        
            % --- Delete selected Nucleus ROIs ---
            if ~isempty(app.NucROIs)
                delIdx = find(app.SelectedNucROIs);
                for i = 1:numel(delIdx)
                    idx = delIdx(i);
                    if isvalid(app.NucROIs(idx))
                        delete(app.NucROIs(idx)); % delete from UI
                    end
                end
                app.NucROIs(delIdx) = [];
                app.CurrentNucMasks(delIdx) = [];
                app.SelectedNucROIs(delIdx) = [];
            end
        end

        % Button pushed function: DrawNucButton
        function DrawNucButtonPushed(app, event)
            if isempty(app.ImagePairs)
                uialert(app.UIFigure, 'No image loaded.', 'Error');
                return;
            end
        
            app.IsDrawingCells = false;
            app.IsDrawingNucs  = true;
            updateDrawingButtons(app);
        
            DisplayNucOverlay(app, app.CurrentIndex);
            title(app.ImageAxes, 'Draw NUCLEUS: click to add vertices, double-click to close.');
        
            hNuc = drawpolygon(app.ImageAxes, ...
                'Color', [0 0 0.6], ...
                'LineWidth', 1.5, ...
                'FaceAlpha', 0.1, ...
                'InteractionsAllowed', 'all');
        
            if isempty(hNuc) || ~isvalid(hNuc)
                app.IsDrawingNucs = false;
                updateDrawingButtons(app);
                title(app.ImageAxes, sprintf('Overlay: %s', app.ImagePairs(app.CurrentIndex).basename), 'Interpreter', 'none');
                return;
            end
        
            posNuc = hNuc.Position;
        
            if isempty(app.NucROIs)
                app.NucROIs = hNuc;
                app.CurrentNucMasks = {posNuc};
                app.SelectedNucROIs = false;
            else
                app.NucROIs(end+1) = hNuc;
                app.CurrentNucMasks{end+1} = posNuc;
                app.SelectedNucROIs(end+1) = false;
            end
        
            hNuc.SelectedColor = [1 0 0];
            addlistener(hNuc, 'ROIClicked', @(roiObj, evt2) toggleNucSelection(app, roiObj));
            addlistener(hNuc, 'ROIMoved',   @(roiObj, evt2) updateNucMask(app, roiObj));
            
            DisplayOverlay(app, app.CurrentIndex);
            app.IsDrawingNucs = false;
            updateDrawingButtons(app);
            bringNucleiToFront(app);
            title(app.ImageAxes, sprintf('Overlay: %s', app.ImagePairs(app.CurrentIndex).basename), 'Interpreter', 'none');
        end

        % Button pushed function: DrawCellButton
        function DrawCellButtonPushed(app, event)
            % Only draw if an image is loaded
            if isempty(app.ImagePairs)
                uialert(app.UIFigure, 'No image loaded.', 'Error');
                return;
            end
        
            % Mark cell drawing as active (for visual only)
            app.IsDrawingCells = true;
            app.IsDrawingNucs  = false;
            updateDrawingButtons(app);
        
            % Optional: instruction in title
            title(app.ImageAxes, 'Draw CELL: click to add vertices, double-click to close.');
        
            % --- Interactive drawing ---
            h = drawpolygon(app.ImageAxes, ...
                'Color', [0 0.6 0], ...
                'LineWidth', 1.5, ...
                'FaceAlpha', 0.1, ...
                'InteractionsAllowed', 'all');
        
            % User may hit Esc / cancel → h may be empty or deleted
            if isempty(h) || ~isvalid(h)
                % Reset state/visuals and return
                app.IsDrawingCells = false;
                updateDrawingButtons(app);
                title(app.ImageAxes, sprintf('Overlay: %s', app.ImagePairs(app.CurrentIndex).basename), 'Interpreter', 'none');
                return;
            end
        
            pos = h.Position;
        
            % Append to arrays
            if isempty(app.CellROIs)
                app.CellROIs = h;
                app.CurrentCellMasks = {pos};
                app.SelectedCellROIs = false;
            else
                app.CellROIs(end+1) = h;
                app.CurrentCellMasks{end+1} = pos;
                app.SelectedCellROIs(end+1) = false;
            end
        
            % Listeners and selection style
            h.SelectedColor = [1 0 0];
            addlistener(h, 'ROIClicked', @(roiObj, evt2) toggleCellSelection(app, roiObj));
            addlistener(h, 'ROIMoved',   @(roiObj, evt2) updateMask(app, roiObj));
            bringNucleiToFront(app);
            
            app.IsDrawingCells = false;
            updateDrawingButtons(app);
            title(app.ImageAxes, sprintf('Overlay: %s', app.ImagePairs(app.CurrentIndex).basename), 'Interpreter', 'none');
        end

        % Value changed function: ImageNumEditField
        function ImageNumEditFieldValueChanged(app, event)
            % If no images loaded yet, do nothing
            if isempty(app.ImagePairs)
                return;
            end
        
            % Get requested index and clamp to valid range
            newIndex = round(app.ImageNumEditField.Value);
            newIndex = max(1, min(newIndex, numel(app.ImagePairs)));
        
            % If nothing actually changed, don't redraw
            if newIndex == app.CurrentIndex
                app.ImageNumEditField.Value = app.CurrentIndex;  % normalize
                return;
            end
        
            % Clear current ROIs & caches
            clearCurrentState(app);
        
            % Update index
            app.CurrentIndex = newIndex;
            app.ImageNumEditField.Value = app.CurrentIndex;  % keep UI in sync
        
            % Display and re-segment
            DisplayOverlay(app, app.CurrentIndex);
            app.NumCellsEditField.Value  = -1;
            app.NumLevelsEditField.Value = 20;
            segmentation(app, app.CurrentIndex);
            
        end

        % Value changed function: NucBrightEditField
        function NucBrightEditFieldValueChanged(app, event)
            DisplayOverlay(app, app.CurrentIndex);
        end

        % Value changed function: CellBrightEditField
        function CellBrightEditFieldValueChanged(app, event)
            DisplayOverlay(app, app.CurrentIndex);
        end

        % Button pushed function: DeleteUnselectedButton
        function DeleteUnselectedButtonPushed(app, event)
            % --- Delete NON-selected Cell ROIs ---
            if ~isempty(app.CellROIs)
            
                % Indices of non-selected cells
                delIdx = find(~app.SelectedCellROIs);
            
                for i = 1:numel(delIdx)
                    idx = delIdx(i);
                    if isvalid(app.CellROIs(idx))
                        delete(app.CellROIs(idx)); % delete ROI from UI
                    end
                end
            
                % Remove all at once
                app.CellROIs(delIdx) = [];
                app.CurrentCellMasks(delIdx) = [];
                app.SelectedCellROIs(delIdx) = [];
            end

            % --- Delete NON-selected Nucleus ROIs ---
            if ~isempty(app.NucROIs)
            
                % Indices of non-selected nuclei
                delIdx = find(~app.SelectedNucROIs);
            
                for i = 1:numel(delIdx)
                    idx = delIdx(i);
                    if isvalid(app.NucROIs(idx))
                        delete(app.NucROIs(idx)); % delete from UI
                    end
                end
            
                % Remove all at once
                app.NucROIs(delIdx) = [];
                app.CurrentNucMasks(delIdx) = [];
                app.SelectedNucROIs(delIdx) = [];
            end

            % --- Unselect all remaining Cell ROIs ---
            if ~isempty(app.CellROIs)
            
                app.SelectedCellROIs(:) = false;
            
                for i = 1:numel(app.CellROIs)
                    if isvalid(app.CellROIs(i))
                        app.CellROIs(i).Selected = false;
                        app.CellROIs(i).Color    = [0 0.6 0];   % green = deselected
                    end
                end
            end

            % --- Unselect all remaining Nucleus ROIs ---
            if ~isempty(app.NucROIs)
            
                app.SelectedNucROIs(:) = false;
            
                for i = 1:numel(app.NucROIs)
                    if isvalid(app.NucROIs(i))
                        app.NucROIs(i).Selected = false;
                        app.NucROIs(i).Color    = [0 0 0.6];    % blue = deselected
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageAxes = uiaxes(app.GridLayout);
            title(app.ImageAxes, 'Title')
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.NucChannelEditFieldLabel = uilabel(app.GridLayout4);
            app.NucChannelEditFieldLabel.Text = 'Nuc Channel';
            app.NucChannelEditField = uieditfield(app.GridLayout4, 'text');
            app.NucChannelEditField.Value = '395S';
            app.CytoChannelEditFieldLabel = uilabel(app.GridLayout4);
            app.CytoChannelEditFieldLabel.Text = 'Cyto Channel';
            app.CytoChannelEditField = uieditfield(app.GridLayout4, 'text');
            app.CytoChannelEditField.Value = '555S';
            app.NumCellsEditFieldLabel = uilabel(app.GridLayout4);
            app.NumCellsEditFieldLabel.Text = 'Num Cells';
            app.NumCellsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NumCellsEditField.Value = -1;
            app.NumLevelsEditFieldLabel = uilabel(app.GridLayout4);
            app.NumLevelsEditFieldLabel.Text = 'Num Levels';
            app.NumLevelsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NumLevelsEditField.Value = 20;
            app.NextButton = uibutton(app.GridLayout4, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed3, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.GridLayout4, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed3, true);
            app.PreviousButton.Text = 'Previous';
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed2, true);
            app.SaveButton.Text = 'Save';
            app.DeleteSelectedButton = uibutton(app.GridLayout4, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.DrawCellButton = uibutton(app.GridLayout4, 'push');
            app.DrawCellButton.ButtonPushedFcn = createCallbackFcn(app, @DrawCellButtonPushed, true);
            app.DrawCellButton.Text = 'Draw Cell';
            app.SegmentButton = uibutton(app.GridLayout4, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed2, true);
            app.SegmentButton.Text = 'Segment';
            app.LoadFolderButton = uibutton(app.GridLayout4, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed3, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.DrawNucButton = uibutton(app.GridLayout4, 'push');
            app.DrawNucButton.ButtonPushedFcn = createCallbackFcn(app, @DrawNucButtonPushed, true);
            app.DrawNucButton.Text = 'Draw Nuc';
            app.EdgeMinimumEditFieldLabel = uilabel(app.GridLayout4);
            app.EdgeMinimumEditFieldLabel.Text = 'Edge Minimum';
            app.EdgeMinimumEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EdgeMinimumEditField.Value = 0.01;
            app.BorderEditFieldLabel = uilabel(app.GridLayout4);
            app.BorderEditFieldLabel.Text = 'Border';
            app.BorderEditField = uieditfield(app.GridLayout4, 'numeric');
            app.BorderEditField.Value = 10;
            app.NucBrightEditFieldLabel = uilabel(app.GridLayout4);
            app.NucBrightEditFieldLabel.Text = 'Nuc Bright';
            app.NucBrightEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NucBrightEditField.ValueChangedFcn = createCallbackFcn(app, @NucBrightEditFieldValueChanged, true);
            app.NucBrightEditField.Value = 3;
            app.CellBrightEditFieldLabel = uilabel(app.GridLayout4);
            app.CellBrightEditFieldLabel.Text = 'Cell Bright';
            app.CellBrightEditField = uieditfield(app.GridLayout4, 'numeric');
            app.CellBrightEditField.ValueChangedFcn = createCallbackFcn(app, @CellBrightEditFieldValueChanged, true);
            app.CellBrightEditField.Value = 5;
            app.ImageNumEditFieldLabel = uilabel(app.GridLayout4);
            app.ImageNumEditFieldLabel.Text = 'Image Num';
            app.ImageNumEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ImageNumEditField.ValueChangedFcn = createCallbackFcn(app, @ImageNumEditFieldValueChanged, true);
            app.ImageNumEditField.Value = 1;
            app.DeleteUnselectedButton = uibutton(app.GridLayout4, 'push');
            app.DeleteUnselectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteUnselectedButtonPushed, true);
            app.DeleteUnselectedButton.Text = 'Delete Unselected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Automatic_Outlining_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end